classdef ModelingTECv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        HeatCoolLamp                  matlab.ui.control.Lamp
        HeatCoolLampLabel             matlab.ui.control.Label
        PWMEditField                  matlab.ui.control.NumericEditField
        PWMEditFieldLabel             matlab.ui.control.Label
        TemperatureEditField          matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel     matlab.ui.control.Label
        ControlButtonGroup            matlab.ui.container.ButtonGroup
        Button3                       matlab.ui.control.ToggleButton
        ProportionalButton            matlab.ui.control.ToggleButton
        ManualButton                  matlab.ui.control.ToggleButton
        TECpowerSwitch                matlab.ui.control.Switch
        TECpowerSwitchLabel           matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ManualTab                     matlab.ui.container.Tab
        HCSwitch                      matlab.ui.control.Switch
        HCSwitchLabel                 matlab.ui.control.Label
        PWMSlider                     matlab.ui.control.Slider
        PWMSliderLabel                matlab.ui.control.Label
        Proportional                  matlab.ui.container.Tab
        HeatLossNumber                matlab.ui.control.NumericEditField
        K_pNumber                     matlab.ui.control.NumericEditField
        HeatLossConstant              matlab.ui.control.Slider
        HeatLossLabel                 matlab.ui.control.Label
        SetTemperatureEditField       matlab.ui.control.NumericEditField
        SetTemperatureEditFieldLabel  matlab.ui.control.Label
        K_pSlider                     matlab.ui.control.Slider
        K_pSliderLabel                matlab.ui.control.Label
        RunSwitch                     matlab.ui.control.Switch
        RunSwitchLabel                matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        PWMPlot                       matlab.ui.control.UIAxes
        TPlot                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        TData % Description
        RoomTemp = 20; % Description
        Array_Start = 2;
        Time;
        PWMData;
        PWM_Now;
        ValuesToTrack=200;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TData = zeros(1,app.ValuesToTrack);
            app.PWMData = zeros(1,app.ValuesToTrack);
            app.Time = zeros(1,app.ValuesToTrack);
            app.TData(:) = app.RoomTemp;
        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
                       dt = .01;
            while app.RunSwitch.Value == 1
                lastIndex = mod(app.Array_Start-2,app.ValuesToTrack)+1; % use modulo to wrap indeies.

                app.PWMData(app.Array_Start) = app.PWMSlider.Value; %get the current PWM Slider Value
                app.Time(app.Array_Start)=app.Time(lastIndex)+dt; % update the time
                % solve dT/dt = -(T-T_room) +PWM/255*(heat_cool_mode)*enable
                % using forward euler
                
                if app.ManualButton.Value == 1
                % change in temperature (T) w time (t) equals
                % heat loss to room -(T-T_room) plus heat from TEC 
                % solve dT/dt = -(T-T_room) +PWM/255*(heat_cool_mode)*enable
                % PWM is divided by 255 just to make numbers nice. Think of
                % it as a thermal mass coefficient 
                % using forward euler
                app.TData(app.Array_Start)=app.TData(lastIndex)+dt*(app.RoomTemp-app.TData(lastIndex)+app.TECpowerSwitch.Value*app.PWMSlider.Value/255*(app.HCSwitch.Value));
                end

                if app.ProportionalButton.Value == 1
                % change in temperature (T) w time (t) equals
                % heat loss to room 
                % -1*HeatLossConst*(T-T_room) 
                % plus heat from TEC 
                % PWM/10*(heat_cool_mode)*enable
                % Here, in proportional control, PWM = K_p * (T_set - T)
                % Furthermore, the PWM is limited to the range of [0,255],
                % like with an Arduino
                % solve dT/dt = -1*HeatLossConst*(T-T_room) + PWM/10*(heat_cool_mode)*enable
                % PWM is divided by 10 to make numbers nice. Think of
                % it as a thermal mass coefficient 
                % using forward euler
                app.PWM_Now =  round(min(app.K_pSlider.Value * abs( (app.SetTemperatureEditField.Value - app.TData(lastIndex)) ), 255));
                app.PWMSlider.Value = app.PWM_Now;
                app.HCSwitch.Value = sign(app.SetTemperatureEditField.Value - app.TData(lastIndex));
                coolingTerm = app.RoomTemp-app.TData(lastIndex);
                app.TData(app.Array_Start)=app.TData(lastIndex)+dt*(app.HeatLossConstant.Value*coolingTerm+app.TECpowerSwitch.Value*app.PWM_Now/10*(app.HCSwitch.Value));
                end  

                % update where the start now is
                app.Array_Start=app.Array_Start+1;
                % wrap it if we've gone past the end
                if app.Array_Start>app.ValuesToTrack
                    app.Array_Start = 1;
                end

                %  heat/cool lamp
                
                if app.HCSwitch.Value == 1
                    app.HeatCoolLamp.Color = [1 0 0]; % red - heat
                    elseif app.HCSwitch.Value == -1
                    app.HeatCoolLamp.Color = [0 1 0]; % green - cool
                end

                app.TemperatureEditField.Value = app.TData(lastIndex);
                app.PWMEditField.Value = app.PWMSlider.Value;
                %plotting loop and data saving commands
                XData = [app.Time(app.Array_Start:end),app.Time(1:app.Array_Start-1)];
                YData = [app.PWMData(app.Array_Start:end),app.PWMData(1:app.Array_Start-1)];
                plot(app.PWMPlot,XData,YData);
                % Plot PWM from [0, 255]
                axis(app.PWMPlot,[min(app.Time),max(app.Time)+eps,0,255])
                % autoscale PWM axis
   %             axis(app.PWMPlot,[min(app.Time),max(app.Time)+eps,min(app.PWMData)-1,max(app.PWMData)+1])

                YData = [app.TData(app.Array_Start:end),app.TData(1:app.Array_Start-1)];

                plot(app.TPlot,XData,YData);
                axis(app.TPlot,[min(app.Time),max(app.Time),min(app.TData)-.1,max(app.TData)+.1])
                drawnow
                pause(dt);

            end            
        end

        % Button down function: ManualTab
        function ManualTabButtonDown(app, event)
            
        end

        % Value changed function: K_pSlider
        function K_pSliderValueChanged(app, event)
            app.K_pNumber.Value = app.K_pSlider.Value;
            
        end

        % Value changed function: HeatLossConstant
        function HeatLossConstantValueChanged(app, event)
            app.HeatLossNumber.Value = app.HeatLossConstant.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {588, 588};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {300, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunSwitchLabel = uilabel(app.LeftPanel);
            app.RunSwitchLabel.Text = 'Run';
            app.RunSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RunSwitch.ItemsData = [0 1];
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.RunSwitch.Value = 0;
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.ManualTab.ButtonDownFcn = createCallbackFcn(app, @ManualTabButtonDown, true);
            app.PWMSliderLabel = uilabel(app.ManualTab);
            app.PWMSliderLabel.Text = 'PWM';
            app.PWMSlider = uislider(app.ManualTab);
            app.PWMSlider.Limits = [0 255];
            app.PWMSlider.MajorTicks = [0 85 170 255];
            app.HCSwitchLabel = uilabel(app.ManualTab);
            app.HCSwitchLabel.Text = 'H/C';
            app.HCSwitch = uiswitch(app.ManualTab, 'slider');
            app.HCSwitch.Items = {'Heat', 'Cool'};
            app.HCSwitch.ItemsData = [1 -1];
            app.HCSwitch.Value = 1;
            app.Proportional = uitab(app.TabGroup);
            app.Proportional.Title = 'Proportional';
            app.K_pSliderLabel = uilabel(app.Proportional);
            app.K_pSliderLabel.Text = 'K_p';
            app.K_pSlider = uislider(app.Proportional);
            app.K_pSlider.Limits = [0 200];
            app.K_pSlider.MajorTicks = [0 50 100 150 200];
            app.K_pSlider.ValueChangedFcn = createCallbackFcn(app, @K_pSliderValueChanged, true);
            app.K_pSlider.MinorTicks = [];
            app.SetTemperatureEditFieldLabel = uilabel(app.Proportional);
            app.SetTemperatureEditFieldLabel.Text = 'Set Temperature';
            app.SetTemperatureEditField = uieditfield(app.Proportional, 'numeric');
            app.SetTemperatureEditField.Limits = [-20 100];
            app.SetTemperatureEditField.Value = 25;
            app.HeatLossLabel = uilabel(app.Proportional);
            app.HeatLossLabel.Text = 'Heat Loss';
            app.HeatLossConstant = uislider(app.Proportional);
            app.HeatLossConstant.Limits = [0 2];
            app.HeatLossConstant.ValueChangedFcn = createCallbackFcn(app, @HeatLossConstantValueChanged, true);
            app.HeatLossConstant.MinorTicks = [];
            app.HeatLossConstant.Value = 1;
            app.K_pNumber = uieditfield(app.Proportional, 'numeric');
            app.K_pNumber.Limits = [0 200];
            app.K_pNumber.ValueDisplayFormat = '%.1f';
            app.HeatLossNumber = uieditfield(app.Proportional, 'numeric');
            app.HeatLossNumber.Limits = [0 2];
            app.HeatLossNumber.ValueDisplayFormat = '%.1f';
            app.HeatLossNumber.Value = 1;
            app.TECpowerSwitchLabel = uilabel(app.LeftPanel);
            app.TECpowerSwitchLabel.Text = 'TEC power';
            app.TECpowerSwitch = uiswitch(app.LeftPanel, 'slider');
            app.TECpowerSwitch.ItemsData = [0 1];
            app.TECpowerSwitch.Value = 0;
            app.ControlButtonGroup = uibuttongroup(app.LeftPanel);
            app.ControlButtonGroup.Title = 'Control';
            app.ManualButton = uitogglebutton(app.ControlButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.ProportionalButton = uitogglebutton(app.ControlButtonGroup);
            app.ProportionalButton.Text = 'Proportional';
            app.Button3 = uitogglebutton(app.ControlButtonGroup);
            app.Button3.Text = 'Button3';
            app.TemperatureEditFieldLabel = uilabel(app.LeftPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TemperatureEditField.ValueDisplayFormat = '%.1f';
            app.PWMEditFieldLabel = uilabel(app.LeftPanel);
            app.PWMEditFieldLabel.Text = 'PWM';
            app.PWMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PWMEditField.ValueDisplayFormat = '%.1f';
            app.HeatCoolLampLabel = uilabel(app.LeftPanel);
            app.HeatCoolLampLabel.Text = 'Heat/Cool';
            app.HeatCoolLamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.TPlot = uiaxes(app.RightPanel);
            title(app.TPlot, 'Temperature (C)')
            xlabel(app.TPlot, 'time')
            ylabel(app.TPlot, 'T (C)')
            zlabel(app.TPlot, 'Z')
            app.PWMPlot = uiaxes(app.RightPanel);
            title(app.PWMPlot, 'PWM')
            xlabel(app.PWMPlot, 'time')
            ylabel(app.PWMPlot, 'PWM')
            zlabel(app.PWMPlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelingTECv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end