classdef TEC_V10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        K_IEditField                  matlab.ui.control.NumericEditField
        K_ILabel                      matlab.ui.control.Label
        K_pEditField                  matlab.ui.control.NumericEditField
        K_pEditFieldLabel             matlab.ui.control.Label
        SettemperatureEditField       matlab.ui.control.NumericEditField
        SettemperatureEditFieldLabel  matlab.ui.control.Label
        RunTECSwitch                  matlab.ui.control.ToggleSwitch
        RunTECSwitchLabel             matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ManualTab                     matlab.ui.container.Tab
        HeatCoolSwitch                matlab.ui.control.Switch
        HeatCoolSwitchLabel           matlab.ui.control.Label
        PWMSlider                     matlab.ui.control.Slider
        PWMSliderLabel                matlab.ui.control.Label
        ProportionalTab               matlab.ui.container.Tab
        ProportionalKSlider           matlab.ui.control.Slider
        ProportionalKSliderLabel      matlab.ui.control.Label
        PITab                         matlab.ui.container.Tab
        IntegralEditField             matlab.ui.control.NumericEditField
        IntegralEditFieldLabel        matlab.ui.control.Label
        ZerointegralButton            matlab.ui.control.Button
        IntegralgainSlider            matlab.ui.control.Slider
        IntegralgainSliderLabel       matlab.ui.control.Label
        ProportionalKSlider_2         matlab.ui.control.Slider
        ProportionalKSliderLabel_2    matlab.ui.control.Label
        Sin                           matlab.ui.container.Tab
        IntegralEditField_2           matlab.ui.control.NumericEditField
        IntegralEditField_2Label      matlab.ui.control.Label
        ZerointegralButton_2          matlab.ui.control.Button
        periodEditField               matlab.ui.control.NumericEditField
        periodEditFieldLabel          matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        PWMEditField                  matlab.ui.control.NumericEditField
        PWMEditFieldLabel             matlab.ui.control.Label
        TemperatureCEditField         matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel    matlab.ui.control.Label
        HeatCoolLamp                  matlab.ui.control.Lamp
        HeatCoolLampLabel             matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        GraphsTab                     matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        PWMPlot                       matlab.ui.control.UIAxes
        TPlot                         matlab.ui.control.UIAxes
        ErrorPlot                     matlab.ui.control.UIAxes
        TalongrodTab                  matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Nov 10, 2023
    % Measures 5 temperatures along rod plus control TEC
    % Scans Angstrom experiment automatically
    % Working

    % Sends 9 control values from Matlab to Arduino
    % Receives 12 display values from Arduino to Matlab
   
   % Modes
   % Manual: Controls current (PWM and H/C) to TEC by hand.
   % Proportional: Controls current (PWM and H/C) to TEC by
   % difference between the user set temperature (T_set) and measured temperature
   % with PWM = K_p * error = K_p * (T_set - T)
   % P/I: Proportional and Integral control. K_p and K_I control the two
   % terms. ResetInt sets Integral term to zero momemtarily. 
   % Sin: Varies T_set periodically in time as 
   % T_set = SinAmp * sin(t/SinPeriod). Inherits P/I control values.
    
   % Works with Arduino Uno code: SerialTestNov8a.ino
   % Arduino sends output of 12 values to Matlab as a ArduinoParams class:  
   % ArduinoParams ARDparams[temp, time, pwmNow, HeatCool, error, Integral, Tset, T1, T2, T3, T4, T5] with formats [%f, %f, %d, %d, %f, %f, %f, %f, %f, %f, %f, %f]

   % Plots strip chart of temperature, error and pwm values.
   % String of 9 control values sent to Arduino (Mode, PWM, H/C, K_p, K_I, T_Set, ResetInt, SinAmp, SinPeriod)
   % The Mode choices are app.TabGroup.SelectedTab.Title. Mode determines how PWM
   % and H/C are calculated, whether by Matlab - Manual, or by Arduino - Proportional, or P/I.
   % Writes to Arduino via Serial line: HeatCool = -1 means cool and HeatCool = 1 means heat.
   % Writes to Arduino via Serial line: pwmNow ranges from [0 255].
   % Tset includes the sinusoidally varying part


    properties (Access = private)
        port4 % port 4 on the arduino
        fileID % this is the output file handle for the arduino
        TempData % Array of temperature data
        PWMData = 0;  % array of PWM values
        errorData % Array of errors (T - T_set)
        TsetData % Array of T_set
        fileName
        temperatureNow = 20; % Measured temperature from thermistor
        tempData1Now = 20;
        tempData2Now = 20;
        tempData3Now = 20;
        tempData4Now = 20;
        tempData5Now = 20;
        temperatureSet = 20; % Set temperature (average set temperature, not sinusoidal)
        TsetFromArduino; % Set temperture calculated in Arduino for display in Matlab
        timeNow = 0; % current time
        pwmNow = 0; % Current PWM value. Ranges from [0,255].
        Array_Start = 2;
        Time;
        Integral = 0; % integral of error
        ResetInt = 1; % if 0 then Integral = 0, if 1 then Integral is unchanged
        HeatCool = 1;  % HeatCool = -1 means cool and HeatCool = 1 means heat.
        ValuesToTrack=300;  % length of strip chart
        tempArrayLength = 100;  % Number of serial reads before writing to file
        tempArrayCounter = 0;  % Counter for serial reads
        tempArray;
        errorNow;
        a; % arduino connection
        lineHandel;
        TempData1; % Array of temperature data
        TempData2; % Array of temperature data
        TempData3; % Array of temperature data
        TempData4; % Array of temperature data
        TempData5; % Array of temperature data
    end
    
    methods (Access = private)
        
        function drawTempPlot(app,tempPlot)  
        % plot time vs temperature and time vs Tset on the same plot

        plot(tempPlot, app.Time, app.TempData, 'LineWidth', 1.5)
 if strcmp(app.TabGroup.SelectedTab.Title, 'Manual') == 0  % show Tset for all plots but "Manual"
        hold(tempPlot,"on")
        plot(tempPlot, app.Time, app.TsetData, 'LineWidth', 0.5, 'Color', 'r')
        hold(tempPlot,"off") 
 end
        % Set the x-axis limits
        xlim(tempPlot, [app.Time(1), app.Time(end)]);
        
if strcmp(app.TabGroup.SelectedTab.Title, 'Manual')
        % Set the y-axis limits. Autoscale for temperature if Manual tab
        ylim(tempPlot, [min(app.TempData) - 0.1, max(app.TempData) + 0.1]);
else
        % Set the y-axis limits. Autoscale.  % if not "Manual" tab, scale by
        % smallest / largest of the temperature and the set temperature.
        ylim(tempPlot, [min(min(app.TempData),min(app.TsetData)) - 0.1, max(max(app.TempData),max(app.TsetData)) + 0.1]);
end


        end

        function drawTempRodPlot(app,tempPlot)  
        % plot time vs temperature for multiple thermistors on the same plot

        plot(tempPlot, app.Time, app.TempData, 'LineWidth', 1.)
        hold(tempPlot,"on")
        plot(tempPlot, app.Time, app.TempData1, 'LineWidth', 1.)
        plot(tempPlot, app.Time, app.TempData2, 'LineWidth', 1.)
        plot(tempPlot, app.Time, app.TempData3, 'LineWidth', 1.)
        plot(tempPlot, app.Time, app.TempData4, 'LineWidth', 1.)
        plot(tempPlot, app.Time, app.TempData5, 'LineWidth', 1.)
        hold(tempPlot,"off") 

        % Set the x-axis limits
        xlim(tempPlot, [app.Time(1), app.Time(end)]);    

        % Set the y-axis limits. Autoscale by
        % smallest / largest of the temperatures


% Combine all arrays into a single matrix
        allArrays = [app.TempData; app.TempData1; app.TempData2; app.TempData3; app.TempData4; app.TempData5];

% Find the minimum and maximum value across all arrays
        ylimMin = min(allArrays(:));
        ylimMax = max(allArrays(:)); 
        ylim(tempPlot, [ylimMin - 0.5, ylimMax + 0.5]);
            
        end

        function drawErrorPlot(app,tempPlot)

        % Plot temperature vs time in stripchart    
        plot(tempPlot,app.Time,app.errorData,'LineWidth',1.5)
    
        app.lineHandel = yline(tempPlot,0,'r');  % draw a red line at zero error
        
        
        % Set the x-axis limits
        xlim(tempPlot, [app.Time(1), app.Time(end)]);
        
        % Set the y-axis limits. Autoscale.
        ylim(tempPlot, [min(app.errorData) - 0.1, max(app.errorData) + 0.1]);
            
        end
        
        
        function drawPWMPlot(app,PWMPlot)
            
            % Plot PWM vs time in stripchart
            plot(PWMPlot,app.Time,app.PWMData,'LineWidth',1.5)

            % Set the x-axis limits
            xlim(PWMPlot, [app.Time(1), app.Time(end)]);
        
            % Set the y-axis limits. Autoscale.
            ylim(PWMPlot, [min(app.PWMData), max(app.PWMData) + 1]);
            
        end
        
        function sendParams2Arduino(app)
          % Create a string to send to the Arduino
          results = sprintf('%s %d %d %f %f %f %d %f %f',...
            app.TabGroup.SelectedTab.Title, app.pwmNow, app.HeatCool, app.K_pEditField.Value, app.K_IEditField.Value, app.SettemperatureEditField.Value, app.ResetInt, app.AmplitudeEditField.Value, app.periodEditField.Value);
            disp(results);
            % Send the parameters to Arduino
            fprintf(app.port4, results); 
            % write parameters to file
            writeParams2File(app);
        end

        function writeParams2File(app)
            % Create a string to send to the file
          results = 'Mode\ttime\tPWM\tHeat/Cool\tK_p\tK_I\tSet Temp\tReset Int\tSin amplitude\tSin period\n';
            % Write all the parameters to the file and append it
            fprintf(app.fileID, results);  
            
            % Create a string to send to the file
          results = sprintf('%s\t%.1f\t%d\t%d\t\t%.1f\t%.1f\t%.1f\t\t%d\t\t%.1f\t\t%.1f\n\n',...
            app.TabGroup.SelectedTab.Title, app.timeNow, app.pwmNow, app.HeatCool, app.K_pEditField.Value, app.K_IEditField.Value, app.SettemperatureEditField.Value, app.ResetInt, app.AmplitudeEditField.Value, app.periodEditField.Value);         
            % Write all the parameters to the file and append it
             fprintf(app.fileID, results);           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Do all the startup housekeeping before starting the while
            % loop
            
            %Find and close any open serial ports
       openSerialPorts = instrfindall('Type', 'serial');

        if ~isempty(openSerialPorts)
            % Close each open serial port
            for i = 1:length(openSerialPorts)
                fclose(openSerialPorts(i));
                delete(openSerialPorts(i));
            end
            disp('Closed all open serial ports.');
        else
            disp('No open serial ports found.');
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % SET THE SERIAL PORT AND BAUDRATE
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % SET THE SERIAL PORT AND BAUDRATE
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        serialPort = '/dev/cu.usbmodem141201';  % Replace with serial port listed in Arduino IDE
        baudRate = 9600;  % Make sure it matches the Arduino cod
        
        % Open the serial port
        app.port4 = serialport(serialPort, baudRate);
        
        % Create a file to save the data in the current director
        
        % Generate a timestamp for the filename
        timestamp = datestr(now, 'yyyymmdd_HHMMSS');
        
        % Create the file name with timestamp
        app.fileName = ['temperature_data_', timestamp, '.txt'];
        
        % Create the file for writing in the current directory
        app.fileID = fopen(app.fileName, 'w');
        
        % Write the file header
        fprintf(app.fileID, 'Temperature\tPWM\tTime\tSet Temperature\tT1\tT2\tT3\n\n');
        
        % To specify a different directory for writing the file, 
        %    provide the full file path when opening the file.
        
        % fileName = '/path/to/directory/temperature_data.txt';
        % fileID = fopen(fileName, 'w');
        
        % Replace "/path/to/directory" with the 
        %  actual directory path where you want to save the file. 
        
             % Initialize the variables
    
         % Read the data from the Arduino serial port
        data = fscanf(app.port4);
        disp(data);
        
        % Extract temperature and time as numbers (2 x 1 double) using str2double
        tokens = split(data);
        numbers = str2double(tokens(~isnan(str2double(tokens))));
        app.temperatureNow = numbers(1);
        app.timeNow = numbers(2);
        app.pwmNow = numbers(3);
        app.HeatCool = numbers(4);
        app.errorNow = numbers(5);
        app.IntegralEditField.Value = numbers(6);
        app.TsetFromArduino = numbers(7);
        app.tempData1Now = numbers(8);
        app.tempData2Now = numbers(9);
        app.tempData3Now = numbers(10);
        app.tempData4Now = numbers(11);
        app.tempData5Now = numbers(12);
       % write temperature to number window
        app.TemperatureCEditField.Value = app.temperatureNow;

        % make arrays that are ValuesToTrack long for stripchart
        app.TempData = zeros(1,app.ValuesToTrack);  % temperature
        app.TempData1 = zeros(1,app.ValuesToTrack);  % temperature
        app.TempData2 = zeros(1,app.ValuesToTrack);  % temperature
        app.TempData3 = zeros(1,app.ValuesToTrack);  % temperature
        app.TempData4 = zeros(1,app.ValuesToTrack);  % temperature
        app.TempData5 = zeros(1,app.ValuesToTrack);  % temperature
        app.TsetData = zeros(1,app.ValuesToTrack);  % set temperature
        app.PWMData = zeros(1,app.ValuesToTrack);   % PWM
        app.Time = zeros(1,app.ValuesToTrack);      % Time
        app.errorData = zeros(1,app.ValuesToTrack); % error
        
        % initialize temperature,error and PWM arrays with current value
   
        app.TempData(:) = app.temperatureNow;
        app.TempData1(:) = app.tempData1Now;
        app.TempData2(:) = app.tempData2Now;
        app.TempData3(:) = app.tempData3Now;
        app.TempData4(:) = app.tempData4Now;
        app.TempData5(:) = app.tempData5Now;
        app.TsetData(:) = app.TsetFromArduino;  % for plotting in Matlab
        app.PWMData(:) = app.pwmNow * app.HeatCool;
        app.errorData(:) = app.errorNow;

        % initialize heat/cool lamp
        app.HeatCool = app.HeatCoolSwitch.Value;
            if app.HeatCool == 1
            app.HeatCoolLamp.Color = [1 0 0]; % red - heat
            elseif app.HeatCool == -1
            app.HeatCoolLamp.Color = [0 1 0]; % green - cool
            end

        % Don't display Error Plot initially
        app.ErrorPlot.Visible = "off";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Matlab releases the serialport by filling it with an empty array.
            app.port4 = [];
            delete(app)
            
        end

        % Value changed function: PWMSlider
        function PWMSliderValueChanged(app, event)
            % read the PWMSlider value and round to integer
            app.pwmNow = round(app.PWMSlider.Value);
            % write PWM value to number window          
            app.PWMEditField.Value = round(app.PWMSlider.Value);
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 

        end

        % Value changed function: HeatCoolSwitch
        function HeatCoolSwitchValueChanged(app, event)
        % HeatCoolSwitch uses ItemsData to send a 1 or -1 to heat or cool
        app.HeatCool = app.HeatCoolSwitch.Value;
        fprintf("HeatCoolSwitch = %d", app.HeatCoolSwitch.Value);
        if app.HeatCool == 1            
             app.HeatCoolLamp.Color = [1 0 0]; % red - heat
        elseif app.HeatCool == -1
             app.HeatCoolLamp.Color = [0 1 0]; % green - cool
        end
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 
      
        end

        % Value changed function: PWMEditField
        function PWMEditFieldValueChanged(app, event)
            app.PWMSlider.Value = round(app.PWMEditField.Value); 
            app.pwmNow =  app.PWMEditField.Value;
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 
     
        end

        % Value changed function: RunTECSwitch
        function RunTECSwitchValueChanged(app, event)
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
        % This is the main function to run the TEC. 
        % Acquires data over serial line from Arduino
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Number of cycles
numCycles = 5;

% Durations for each cycle (in minutes)
durations = [1, 1, 2, 3, 4];

% Convert durations to seconds
durationsInSeconds = durations * 60;

% main while loop
while app.RunTECSwitch.Value == "On"

for i = 1:numCycles
    % Start continuous operation during one interval of constant period
    disp(['Cycle ', num2str(i), ': Running continuously for ', num2str(durations(i))*10, ' minutes...']);
    
    % Convert duration to end time
    endTime = datetime('now') + seconds(durationsInSeconds(i)*10);  % stay at constant period for 10 periods.

    % set period in seconds
    app.periodEditField.Value = durationsInSeconds(i);
    % Create a string of all the controls and send to the Arduino
    sendParams2Arduino(app); 

    
    % Run continuously until the end time
    while datetime('now') < endTime
        % Your continuous operations here
         % Read the ARDparams data from the Arduino serial port
        data = fscanf(app.port4);
        % disp(data);
 
        % Arduino sends to Matlab different strings. We only want the one with 
        % the right format. That string begins with the
        % first word "Temperature" so we only want strings that begin
        % with 'Temp'. Other strings are for diagnostics. We print
        % diagnoses to the Matlab command window above, but we don't want to parse
        % out the information into "numbers" so we continue the while loop.
        if ~strcmp(data(1:4),'Temp') % if the first 4 characters are not 'Temp'
            continue
        end

        % Extract numbers from arduino serial line using str2double
        % temperature and time come from Arduino
        tokens = split(data);
        % we expect 10 numbers from the Arduino
        numbers = str2double(tokens(~isnan(str2double(tokens))));
        app.temperatureNow = numbers(1);
        app.timeNow = numbers(2);
        app.pwmNow = numbers(3);
        app.HeatCool = numbers(4);
        app.errorNow = numbers(5);
        app.IntegralEditField.Value = numbers(6);
        app.TsetFromArduino = numbers(7);
        app.tempData1Now = numbers(8);
        app.tempData2Now = numbers(9);
        app.tempData3Now = numbers(10);
        app.tempData4Now = numbers(11);
        app.tempData5Now = numbers(12);
        % Shift the time, temperature, error and PWM arrays
        app.Time = [app.Time(2:end), app.timeNow];
        app.TempData = [app.TempData(2:end), app.temperatureNow];
        app.TempData1 = [app.TempData1(2:end), app.tempData1Now];
        app.TempData2 = [app.TempData2(2:end), app.tempData2Now];
        app.TempData3 = [app.TempData3(2:end), app.tempData3Now];
        app.TempData4 = [app.TempData4(2:end), app.tempData4Now];
        app.TempData5 = [app.TempData5(2:end), app.tempData5Now];
        app.TsetData = [app.TsetData(2:end), app.TsetFromArduino];
        app.errorData = [app.errorData(2:end), app.errorNow];
        app.PWMData = [app.PWMData(2:end), app.pwmNow * app.HeatCool];

        % Update the temperature and PWM strip chart data
        %     in the Manual plotting window

        if strcmp(app.TabGroup.SelectedTab.Title,'Manual')   % manual control
            app.drawTempPlot(app.TPlot)   % plot Temperature vs time
            app.drawPWMPlot(app.PWMPlot)  % plot PWM vs time

            app.K_IEditField.Visible = "off";  % hide K_I edit field
            app.K_pEditField.Visible = "off";   % hide K_p edit field
            app.K_ILabel.Visible = "off";
            app.K_pEditFieldLabel.Visible = "off";
            app.ErrorPlot.Visible = "off";  % hide Error plot
            axesHandlesToChildObjects = findobj(app.ErrorPlot, 'Type', 'line');  % hide lines from Error plot
              if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
              end
              delete(app.lineHandel);

        end
 
        % Update the temperature and PWM strip chart data
        %     in the Proportional plotting window

        if strcmp(app.TabGroup.SelectedTab.Title,'Proportional') % proportional control  (P)

            % plot in proportional window
            app.drawTempPlot(app.TPlot)
            app.drawPWMPlot(app.PWMPlot)
            app.drawErrorPlot(app.ErrorPlot)
            app.K_IEditField.Visible = "off";
            app.K_pEditField.Visible = "on";
            app.K_ILabel.Visible = "off";
            app.K_pEditFieldLabel.Visible = "on";
            app.ErrorPlot.Visible = "on";           
        end

        if strcmp(app.TabGroup.SelectedTab.Title,'P/I') % proportional / integral control (PI)

            % plot in proportional window
            app.drawTempPlot(app.TPlot)
            app.drawPWMPlot(app.PWMPlot)
            app.drawErrorPlot(app.ErrorPlot)
            app.K_IEditField.Visible = "on";
            app.K_pEditField.Visible = "on";
            app.K_ILabel.Visible = "on";
            app.K_pEditFieldLabel.Visible = "on";
            app.ErrorPlot.Visible = "on";
                    
        end

        if strcmp(app.TabGroup.SelectedTab.Title,'Sin') % Use PI control. Vary T_set sinusoidally

            % plot in proportional window
            app.drawTempPlot(app.TPlot)
            app.drawPWMPlot(app.PWMPlot)
            app.drawErrorPlot(app.ErrorPlot)
            app.K_IEditField.Visible = "on";
            app.K_pEditField.Visible = "on";
            app.K_ILabel.Visible = "on";
            app.K_pEditFieldLabel.Visible = "on";
            app.ErrorPlot.Visible = "on";
            app.IntegralEditField_2.Value = app.IntegralEditField.Value;
                    
        end

         if strcmp(app.TabGroup2.SelectedTab.Title,'T along rod') % plot a bunch of temperatures
            % plot in Temp along rod window
            app.drawTempRodPlot(app.UIAxes)               
        end


        % Update the plot
        drawnow;

        % update HeatCool Lamp
        if app.HeatCool == 1            
            app.HeatCoolLamp.Color = [1 0 0]; % red - heat
        elseif app.HeatCool == -1
            app.HeatCoolLamp.Color = [0 1 0]; % green - cool
        end

        % write temperature value to number window
        app.TemperatureCEditField.Value = app.temperatureNow;
        % % write PWM value to number window
        app.PWMEditField.Value = app.pwmNow;

        % Display the extracted numbers on Matlab console
        % disp(['Temp: ', num2str(app.temperatureNow), ' (C) ', 'Time: ', num2str(app.timeNow), ' (s)  PWM: ' ,num2str(app.pwmNow), '  Heat/Cool: ', num2str(app.HeatCool)]);
        % disp(data);

        % Store 9 items; [temperature, PWM, time, Temp set, T1, T2, T3, T4, T5] in the file 'app.fileName'
        app.tempArrayCounter = app.tempArrayCounter + 1;
        app.tempArray(app.tempArrayCounter, :) = [app.temperatureNow, app.PWMData(end), app.timeNow, app.TsetData(end), app.tempData1Now, app.tempData2Now, app.tempData3Now, app.tempData4Now, app.tempData5Now];
        
        % Check if it's time to write to file
        if app.tempArrayCounter == app.tempArrayLength
            % Write tempArray to the file and append it
            % dlmwrite(app.fileName, app.tempArray, '-append');
            writematrix(app.tempArray, app.fileName,'Delimiter','tab','WriteMode','append');
            
            % Reset the tempArrayCounter and tempArray
            app.tempArrayCounter = 0;
            app.tempArray = [];
        end
    
                
  end   % end of while loop for duration of constant period

    % Execute commands after continuous operation
    disp('Executing commands after continuous operation...');
    
    % Your commands here
    end   % end of for loop for changing period

    % turn off PWM after frequency scan
    app.K_pEditField.Value = 0;
    app.K_IEditField.Value = 0;
    % Create a string of all the controls and send to the Arduino
    sendParams2Arduino(app);

end   % end of main while loop

        if app.RunTECSwitch.Value == "Off"
            app.pwmNow = 0; % turn off power to the TEC
            % write PWM value to number and slider window
            app.PWMEditField.Value = app.pwmNow;
            app.PWMSlider.Value = app.pwmNow; 

            % Create a string of all the controls and send to the Arduino

            results = sprintf('%s %d %d %f %f %f %d %f %f',...
            "TurnOff", app.pwmNow, app.HeatCool, app.K_pEditField.Value, app.K_IEditField.Value, app.SettemperatureEditField.Value, app.ResetInt, app.AmplitudeEditField.Value, app.periodEditField.Value);
            disp(results);
            % Send the parameters to Arduino
            fprintf(app.port4, results);  

        end
        end

        % Value changed function: SettemperatureEditField
        function SettemperatureEditFieldValueChanged(app, event)
            app.temperatureSet = app.SettemperatureEditField.Value;  % average set temperature
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 

        end

        % Value changed function: ProportionalKSlider
        function ProportionalKSliderValueChanged(app, event)
            app.K_pEditField.Value = app.ProportionalKSlider.Value;
            app.ProportionalKSlider_2.Value = min(app.ProportionalKSlider.Value);
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 

        end

        % Button pushed function: ZerointegralButton
        function ZerointegralButtonPushed(app, event)
            app.ResetInt = 0;  % momemtarily reset the integral

            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 

            app.ResetInt = 1;    % restore integration

        end

        % Value changed function: ProportionalKSlider_2
        function ProportionalKSlider_2ValueChanged(app, event)
        app.K_pEditField.Value = app.ProportionalKSlider_2.Value;
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 

        end

        % Value changed function: IntegralgainSlider
        function IntegralgainSliderValueChanged(app, event)
            app.K_IEditField.Value = app.IntegralgainSlider.Value;
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 

        end

        % Value changed function: K_IEditField
        function K_IEditFieldValueChanged(app, event)
            app.IntegralgainSlider.Value = app.K_IEditField.Value;
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 

            
        end

        % Value changed function: K_pEditField
        function K_pEditFieldValueChanged(app, event)
            app.ProportionalKSlider.Value = app.K_pEditField.Value;
            app.ProportionalKSlider_2.Value = app.K_pEditField.Value;  
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title, 'Manual')
                 app.PWMSlider.Value = app.PWMEditField.Value;
                 app.pwmNow =  app.PWMEditField.Value;
                 app.HeatCool = app.HeatCoolSwitch.Value;
            end

            if strcmp(app.TabGroup.SelectedTab.Title, 'Proportional')
                app.ProportionalKSlider.Value = app.K_pEditField.Value;
            end

            if strcmp(app.TabGroup.SelectedTab.Title, 'P/I')
                app.ProportionalKSlider_2.Value = app.ProportionalKSlider.Value;
            end

            app.temperatureSet = app.SettemperatureEditField.Value;  % this is the average Tset
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 

            
        end

        % Value changed function: periodEditField
        function periodEditFieldValueChanged(app, event)
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app);
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app);
        end

        % Button pushed function: ZerointegralButton_2
        function ZerointegralButton_2Pushed(app, event)
            app.ResetInt = 0;  % momemtarily reset the integral

            % Create a string of all the controls and end to the Arduino
            sendParams2Arduino(app); 

            app.ResetInt = 1;    % restore integration

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {554, 554};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HeatCoolLampLabel = uilabel(app.LeftPanel);
            app.HeatCoolLampLabel.Text = {'Heat'; 'Cool'};
            app.HeatCoolLamp = uilamp(app.LeftPanel);
            app.TemperatureCEditFieldLabel = uilabel(app.LeftPanel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C)';
            app.TemperatureCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TemperatureCEditField.ValueDisplayFormat = '%.1f';
            app.TemperatureCEditField.Editable = 'off';
            app.PWMEditFieldLabel = uilabel(app.LeftPanel);
            app.PWMEditFieldLabel.Text = 'PWM';
            app.PWMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PWMEditField.Limits = [0 255];
            app.PWMEditField.ValueDisplayFormat = '%d';
            app.PWMEditField.ValueChangedFcn = createCallbackFcn(app, @PWMEditFieldValueChanged, true);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.PWMSliderLabel = uilabel(app.ManualTab);
            app.PWMSliderLabel.Text = 'PWM';
            app.PWMSlider = uislider(app.ManualTab);
            app.PWMSlider.Limits = [0 255];
            app.PWMSlider.MajorTicks = [0 64 128 192 255];
            app.PWMSlider.ValueChangedFcn = createCallbackFcn(app, @PWMSliderValueChanged, true);
            app.HeatCoolSwitchLabel = uilabel(app.ManualTab);
            app.HeatCoolSwitchLabel.Text = 'Heat/Cool';
            app.HeatCoolSwitch = uiswitch(app.ManualTab, 'slider');
            app.HeatCoolSwitch.Items = {'Heat', 'Cool'};
            app.HeatCoolSwitch.ItemsData = [1 -1];
            app.HeatCoolSwitch.ValueChangedFcn = createCallbackFcn(app, @HeatCoolSwitchValueChanged, true);
            app.HeatCoolSwitch.Value = 1;
            app.ProportionalTab = uitab(app.TabGroup);
            app.ProportionalTab.Title = 'Proportional';
            app.ProportionalKSliderLabel = uilabel(app.ProportionalTab);
            app.ProportionalKSliderLabel.Text = 'Proportional (K)';
            app.ProportionalKSlider = uislider(app.ProportionalTab);
            app.ProportionalKSlider.Limits = [0 400];
            app.ProportionalKSlider.ValueChangedFcn = createCallbackFcn(app, @ProportionalKSliderValueChanged, true);
            app.PITab = uitab(app.TabGroup);
            app.PITab.Title = 'P/I';
            app.ProportionalKSliderLabel_2 = uilabel(app.PITab);
            app.ProportionalKSliderLabel_2.Text = 'Proportional gain';
            app.ProportionalKSlider_2 = uislider(app.PITab);
            app.ProportionalKSlider_2.Limits = [0 400];
            app.ProportionalKSlider_2.ValueChangedFcn = createCallbackFcn(app, @ProportionalKSlider_2ValueChanged, true);
            app.IntegralgainSliderLabel = uilabel(app.PITab);
            app.IntegralgainSliderLabel.Text = 'Integral gain';
            app.IntegralgainSlider = uislider(app.PITab);
            app.IntegralgainSlider.Limits = [0 2];
            app.IntegralgainSlider.ValueChangedFcn = createCallbackFcn(app, @IntegralgainSliderValueChanged, true);
            app.IntegralgainSlider.MinorTicks = [0.2 0.6 1 1.4 1.8];
            app.ZerointegralButton = uibutton(app.PITab, 'push');
            app.ZerointegralButton.ButtonPushedFcn = createCallbackFcn(app, @ZerointegralButtonPushed, true);
            app.ZerointegralButton.Text = 'Zero integral';
            app.IntegralEditFieldLabel = uilabel(app.PITab);
            app.IntegralEditFieldLabel.Text = 'Integral';
            app.IntegralEditField = uieditfield(app.PITab, 'numeric');
            app.Sin = uitab(app.TabGroup);
            app.Sin.Title = 'Sin';
            app.AmplitudeEditFieldLabel = uilabel(app.Sin);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.Sin, 'numeric');
            app.AmplitudeEditField.Limits = [0 20];
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.periodEditFieldLabel = uilabel(app.Sin);
            app.periodEditFieldLabel.Text = 'period';
            app.periodEditField = uieditfield(app.Sin, 'numeric');
            app.periodEditField.Limits = [10 300];
            app.periodEditField.ValueChangedFcn = createCallbackFcn(app, @periodEditFieldValueChanged, true);
            app.periodEditField.Value = 60;
            app.ZerointegralButton_2 = uibutton(app.Sin, 'push');
            app.ZerointegralButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZerointegralButton_2Pushed, true);
            app.ZerointegralButton_2.Text = 'Zero integral';
            app.IntegralEditField_2Label = uilabel(app.Sin);
            app.IntegralEditField_2Label.Text = 'Integral';
            app.IntegralEditField_2 = uieditfield(app.Sin, 'numeric');
            app.RunTECSwitchLabel = uilabel(app.LeftPanel);
            app.RunTECSwitchLabel.Text = 'Run TEC';
            app.RunTECSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.RunTECSwitch.ValueChangedFcn = createCallbackFcn(app, @RunTECSwitchValueChanged, true);
            app.SettemperatureEditFieldLabel = uilabel(app.LeftPanel);
            app.SettemperatureEditFieldLabel.Text = 'Set temperature';
            app.SettemperatureEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SettemperatureEditField.Limits = [-10 60];
            app.SettemperatureEditField.ValueDisplayFormat = '%.1f';
            app.SettemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @SettemperatureEditFieldValueChanged, true);
            app.SettemperatureEditField.Value = 20;
            app.K_pEditFieldLabel = uilabel(app.LeftPanel);
            app.K_pEditFieldLabel.Interpreter = 'tex';
            app.K_pEditFieldLabel.Visible = 'off';
            app.K_pEditFieldLabel.Text = 'K_p';
            app.K_pEditField = uieditfield(app.LeftPanel, 'numeric');
            app.K_pEditField.Limits = [0 400];
            app.K_pEditField.ValueDisplayFormat = '%.1f';
            app.K_pEditField.ValueChangedFcn = createCallbackFcn(app, @K_pEditFieldValueChanged, true);
            app.K_pEditField.Visible = 'off';
            app.K_ILabel = uilabel(app.LeftPanel);
            app.K_ILabel.Interpreter = 'tex';
            app.K_ILabel.Visible = 'off';
            app.K_ILabel.Text = 'K_I';
            app.K_IEditField = uieditfield(app.LeftPanel, 'numeric');
            app.K_IEditField.Limits = [0 2];
            app.K_IEditField.ValueDisplayFormat = '%.2f';
            app.K_IEditField.ValueChangedFcn = createCallbackFcn(app, @K_IEditFieldValueChanged, true);
            app.K_IEditField.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.GraphsTab = uitab(app.TabGroup2);
            app.GraphsTab.Title = 'Graphs';
            app.GridLayout2 = uigridlayout(app.GraphsTab);
            app.ErrorPlot = uiaxes(app.GridLayout2);
            title(app.ErrorPlot, 'Error: T_{set} - T (C)')
            xlabel(app.ErrorPlot, 'Time (s)')
            ylabel(app.ErrorPlot, 'Error (C)')
            zlabel(app.ErrorPlot, 'Z')
            app.TPlot = uiaxes(app.GridLayout2);
            title(app.TPlot, 'Temperature (C)')
            xlabel(app.TPlot, 'Time (s)')
            ylabel(app.TPlot, 'Temperature (C)')
            zlabel(app.TPlot, 'Z')
            app.PWMPlot = uiaxes(app.GridLayout2);
            title(app.PWMPlot, 'PWM')
            xlabel(app.PWMPlot, 'Time (s)')
            ylabel(app.PWMPlot, 'PWM')
            zlabel(app.PWMPlot, 'Z')
            app.TalongrodTab = uitab(app.TabGroup2);
            app.TalongrodTab.Title = 'T along rod';
            app.UIAxes = uiaxes(app.TalongrodTab);
            title(app.UIAxes, 'Temperature along the rod')
            xlabel(app.UIAxes, 'time (sec)')
            ylabel(app.UIAxes, 'temperature (C)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEC_V10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end