classdef TEC_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ControlButtonGroup              matlab.ui.container.ButtonGroup
        PIButton                        matlab.ui.control.ToggleButton
        ProportionalButton              matlab.ui.control.ToggleButton
        ManualButton                    matlab.ui.control.ToggleButton
        RunTECSwitch                    matlab.ui.control.ToggleSwitch
        RunTECSwitchLabel               matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ManualTab                       matlab.ui.container.Tab
        HeatCoolSwitch                  matlab.ui.control.Switch
        HeatCoolSwitchLabel             matlab.ui.control.Label
        PWMSlider                       matlab.ui.control.Slider
        PWMSliderLabel                  matlab.ui.control.Label
        ProportionalTab                 matlab.ui.container.Tab
        SettemperatureEditField         matlab.ui.control.NumericEditField
        SettemperatureEditFieldLabel    matlab.ui.control.Label
        ProportionalKSlider             matlab.ui.control.Slider
        ProportionalKSliderLabel        matlab.ui.control.Label
        PITab                           matlab.ui.container.Tab
        SettemperatureEditField_2       matlab.ui.control.NumericEditField
        SettemperatureEditField_2Label  matlab.ui.control.Label
        IntegralEditField               matlab.ui.control.NumericEditField
        IntegralEditFieldLabel          matlab.ui.control.Label
        ZerointegralButton              matlab.ui.control.Button
        IntegralgainSlider              matlab.ui.control.Slider
        IntegralgainSliderLabel         matlab.ui.control.Label
        ProportionalKSlider_2           matlab.ui.control.Slider
        ProportionalKSliderLabel_2      matlab.ui.control.Label
        PWMEditField                    matlab.ui.control.NumericEditField
        PWMEditFieldLabel               matlab.ui.control.Label
        TemperatureCEditField           matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        HeatCoolLamp                    matlab.ui.control.Lamp
        HeatCoolLampLabel               matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        ManualTab_2                     matlab.ui.container.Tab
        PWMPlot                         matlab.ui.control.UIAxes
        TPlot                           matlab.ui.control.UIAxes
        ProportionalTab_2               matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        PropPWMPlot                     matlab.ui.control.UIAxes
        TPlot_2                         matlab.ui.control.UIAxes
        PropErrorPlot                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Sept. 30, 2023
   
   % Manual: Controls current (PWM and direction) to TEC by hand.
   % Proportional: Controls current (PWM and direction) to TEC by
   % difference between the user set temperature and measured temperature.
    
   % Works with Arduino Uno code: ThermistorTimeSerialOutForMatlab_20230930.ino
   % Plots strip chart of temperature and pwm values.
   % Writes to Arduino via Serial line: HeatCool = 0 means cool and HeatCool = 1 means heat.
   % Writes to Arduino via Serial line: pwmNow ranges from [0,255]. 
   
   
    properties (Access = private)
        port4 % port 4 on the arduino
        fileID % this is the output file handle for the arduino
        TempData % Array of temperature data
        PWMData = 0;  % array of PWM values
        errorData % Array of errors (T - T_set)
        fileName
        temperatureNow = 20; % Measured temperature from thermistor
        temperatureSet = 20; % Set temperature
        timeNow = 0; % current time
        pwmNow = 0; % Current PWM value. Ranges from [0,255].
        Array_Start = 2;
        Time;
        Integral = 0; % integral of error
        HeatCool = 1;  % HeatCool = 0 means cool and HeatCool = 1 means heat.
        ValuesToTrack=200;  % length of strip chart
        tempArrayLength = 100;  % Number of serial reads before writing to file
        tempArrayCounter = 0;  % Counter for serial reads
        tempArray;
        errorNow;
        a; % arduino connection
    end
    
    methods (Access = private)
        
        function drawTempPlot(app,tempPlot)

        % Plot temperature vs time in stripchart    
        plot(tempPlot,app.Time,app.TempData)
        
        % Set the x-axis limits
        xlim(tempPlot, [app.Time(1), app.Time(end)]);
        
        % Set the y-axis limits. Autoscale.
        ylim(tempPlot, [min(app.TempData) - 0.1, max(app.TempData) + 0.1]);
            
        end

        function drawErrorPlot(app,tempPlot)

        % Plot temperature vs time in stripchart    
        plot(tempPlot,app.Time,app.errorData)
        
        % Set the x-axis limits
        xlim(tempPlot, [app.Time(1), app.Time(end)]);
        
        % Set the y-axis limits. Autoscale.
        ylim(tempPlot, [min(app.errorData) - 0.1, max(app.errorData) + 0.1]);
            
        end
        
        
        function drawPWMPlot(app,PWMPlot)
            
            % Plot PWM vs time in stripchart
            plot(PWMPlot,app.Time,app.PWMData)

            % Set the x-axis limits
            xlim(PWMPlot, [app.Time(1), app.Time(end)]);
        
            % Set the y-axis limits. Autoscale.
            ylim(PWMPlot, [min(app.PWMData), max(app.PWMData) + 1]);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Do all the startup housekeeping before starting the while
            % loop
            
            %Find and close any open serial ports
       openSerialPorts = instrfindall('Type', 'serial');

        if ~isempty(openSerialPorts)
            % Close each open serial port
            for i = 1:length(openSerialPorts)
                fclose(openSerialPorts(i));
                delete(openSerialPorts(i));
            end
            disp('Closed all open serial ports.');
        else
            disp('No open serial ports found.');
        end
        
        % Set the serial port and baud rate
        serialPort = '/dev/cu.usbmodem141301';  % Replace with serial port listed in Arduino IDE
        baudRate = 9600;  % Make sure it matches the Arduino cod
        
        % Open the serial port
        app.port4 = serialport(serialPort, baudRate);
        %fopen(app.port4);
        
        % Create a file to save the data in the current director
        
        % Generate a timestamp for the filename
        timestamp = datestr(now, 'yyyymmdd_HHMMSS');
        
        % Create the file name with timestamp
        app.fileName = ['temperature_data_', timestamp, '.txt'];
        
        % Create the file for writing in the current directory
        app.fileID = fopen(app.fileName, 'w');
        
        % Write the file header
        fprintf(app.fileID, 'Temperature\tTime\n');
        
        % To specify a different directory for writing the file, 
        %    provide the full file path when opening the file.
        
        % fileName = '/path/to/directory/temperature_data.txt';
        % fileID = fopen(fileName, 'w');
        
        % Replace "/path/to/directory" with the 
        %  actual directory path where you want to save the file. 
        
             % Initialize the variables
    
         % Read the data from the Arduino serial port
        data = fscanf(app.port4);
        
        % Extract temperature and time as numbers (2 x 1 double) using str2double
        tokens = split(data);
        numbers = str2double(tokens(~isnan(str2double(tokens))));
        app.temperatureNow = numbers(1);
        app.timeNow = numbers(2);
        % calculate error
        app.errorNow = app.temperatureSet - app.temperatureNow;
        % write temperature to number window
        app.TemperatureCEditField.Value = app.temperatureNow;

        % make arrays that are ValuesToTrack long for stripchart
        app.TempData = zeros(1,app.ValuesToTrack);  % temperature
        app.PWMData = zeros(1,app.ValuesToTrack);   % PWM
        app.Time = zeros(1,app.ValuesToTrack);      % Time
        app.errorData = zeros(1,app.ValuesToTrack); % error
        
        % initialize temperature,error and PWM arrays with current value
        app.TempData(:) = app.temperatureNow;
        app.PWMData(:) = app.pwmNow;
        app.errorData(:) = app.errorNow;

        % initialize heat/cool lamp
        app.HeatCool = app.HeatCoolSwitch.Value;
            if app.HeatCool == 1
            app.HeatCoolLamp.Color = [1 0 0]; % red - heat
            elseif app.HeatCool == 0
            app.HeatCoolLamp.Color = [0 1 0]; % green - cool
        end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Matlab releases the serialport by filling it with an empty array.
            app.port4 = [];
            delete(app)
            
        end

        % Value changed function: PWMSlider
        function PWMSliderValueChanged(app, event)
            % read the PWMSlider value and round to integer
            app.pwmNow = round(app.PWMSlider.Value);
            % write PWM value to number window
            app.PWMEditField.Value = app.pwmNow;
            % Send the PWM value to the Arduino
            fprintf(app.port4, 'PWM %d\n', app.pwmNow);         
        end

        % Value changed function: HeatCoolSwitch
        function HeatCoolSwitchValueChanged(app, event)
        % HeatCoolSwitch uses ItemsData to send a 1 or 0 to heat or cool
        app.HeatCool = app.HeatCoolSwitch.Value;
        if app.HeatCool == 1            
                app.HeatCoolLamp.Color = [1 0 0]; % red - heat
            elseif app.HeatCool == 0
            app.HeatCoolLamp.Color = [0 1 0]; % green - cool
            end
            % Send the Heat/Cool value to the Arduino (Heat=1, Cool=0)
            fprintf(app.port4, 'Heat/Cool %d\n', app.HeatCool);
        end

        % Value changed function: PWMEditField
        function PWMEditFieldValueChanged(app, event)
            app.PWMSlider.Value = app.PWMEditField.Value;       
        end

        % Value changed function: RunTECSwitch
        function RunTECSwitchValueChanged(app, event)
        % This is the main function to run the TEC. 
        % Acquires data over serial line from Arduino


        while app.RunTECSwitch.Value == "On"
        % Read the data from the Arduino serial port
        data = fscanf(app.port4);
        
        % Extract numbers from arduino serial line (3 x 1 double) using str2double
        % temperature and time come from Arduino
        tokens = split(data);
        numbers = str2double(tokens(~isnan(str2double(tokens))));
        app.temperatureNow = numbers(1);
        app.timeNow = numbers(2);
        % calculate error (negative feedback)
        app.errorNow = app.temperatureSet - app.temperatureNow;
           
        % Shift the time, temperature, error and PWM arrays
        app.Time = [app.Time(2:end), app.timeNow];
        app.TempData = [app.TempData(2:end), app.temperatureNow];
        app.errorData = [app.errorData(2:end), app.errorNow];
        % PWM comes from Matlab
        app.PWMData = [app.PWMData(2:end), app.pwmNow];


        % Update the temperature and PWM strip chart data
        %     in the Manual plotting window

        if app.ManualButton.Value == 1   % manual control
            app.drawTempPlot(app.TPlot)
            app.drawPWMPlot(app.PWMPlot)
            app.PWMSlider.Enable = 'on';
            app.HeatCoolSwitch.Enable = 'on';
            app.ProportionalKSlider.Enable = 'off';
        end
 
        % Update the temperature and PWM strip chart data
        %     in the Proportional plotting window

        if app.ProportionalButton.Value == 1  % proportional control  (P)

            % plot in proportional window
            app.drawTempPlot(app.TPlot_2)
            app.drawPWMPlot(app.PropPWMPlot)
            app.drawErrorPlot(app.PropErrorPlot)
            app.PWMSlider.Enable = 'off';
            app.HeatCoolSwitch.Enable = 'off';
            app.ProportionalKSlider.Enable = 'on';

            % proportional control  (P)
            app.pwmNow = round(min(max(app.ProportionalKSlider.Value * abs(app.errorNow), 0), 255));

            % heat or cool
            if app.errorNow > 0   % heat
                 app.HeatCool = 1; 
            elseif app.errorNow <= 0 % cool
                 app.HeatCool = 0;
            end
            
            % update HeatCool Lamp
            if app.HeatCool == 1            
                app.HeatCoolLamp.Color = [1 0 0]; % red - heat
            elseif app.HeatCool == 0
            app.HeatCoolLamp.Color = [0 1 0]; % green - cool
            end

            % Send the Heat/Cool value to the Arduino (Heat=1, Cool=0)
            fprintf(app.port4, 'Heat/Cool %d\n', app.HeatCool);

            % Send the PWM value to the Arduino
            fprintf(app.port4, 'PWM %d\n', app.pwmNow); 
            
        end

        if app.PIButton.Value == 1  % proportional / integral control (PI)

            % plot in proportional window
            app.drawTempPlot(app.TPlot_2)
            app.drawPWMPlot(app.PropPWMPlot)
            app.drawErrorPlot(app.PropErrorPlot)
            app.PWMSlider.Enable = 0;
            app.HeatCoolSwitch.Enable = 'off';
            app.ProportionalKSlider.Enable = 'off';
            app.ProportionalKSlider_2.Enable = 'on';
            app.IntegralgainSlider.Enable = 'on';

            % proportional integral control
            
            dt = app.Time(end) - app.Time(end-1); % time interval for last measurement
            app.Integral = app.Integral + dt * app.errorNow;  % update integral of error
            u = app.ProportionalKSlider_2.Value * app.errorNow + app.IntegralgainSlider.Value * app.Integral;  % calculate PI feedback
            app.pwmNow = min(abs(round(u)),255);  % magnitude of PI feedback term u limited to a max of 255 for arduino PWM

            app.IntegralEditField.Value = app.IntegralgainSlider.Value * app.Integral;  % write Integral to screen
            % heat or cool
            if u > 0   % heat
                 app.HeatCool = 1; 
            elseif u <= 0 % cool
                 app.HeatCool = 0;
            end
            
            % update HeatCool Lamp
            if app.HeatCool == 1            
                app.HeatCoolLamp.Color = [1 0 0]; % red - heat
            elseif app.HeatCool == 0
            app.HeatCoolLamp.Color = [0 1 0]; % green - cool
            end

            % Send the Heat/Cool value to the Arduino (Heat=1, Cool=0)
            fprintf(app.port4, 'Heat/Cool %d\n', app.HeatCool);

            % Send the PWM value to the Arduino
            fprintf(app.port4, 'PWM %d\n', app.pwmNow); 
            
        end


        % stuff to do for all types of control
        % Update the plot
        drawnow;

        % write temperature value to number window
        app.TemperatureCEditField.Value = app.temperatureNow;
        % write PWM value to number window
        app.PWMEditField.Value = app.pwmNow;

        % Display the extracted numbers on Matlab console
        disp(['Temp: ', num2str(app.temperatureNow), ' (C) ', 'Time: ', num2str(app.timeNow), ' (s)  PWM: ' ,num2str(app.pwmNow), '  Heat/Cool: ', num2str(app.HeatCool)]);
    
        % Store the data in tempArray
        app.tempArrayCounter = app.tempArrayCounter + 1;
        app.tempArray(app.tempArrayCounter, :) = [app.temperatureNow, app.timeNow];
        
        % Check if it's time to write to file
        if app.tempArrayCounter == app.tempArrayLength
            % Write tempArray to the file and append it
            dlmwrite(app.fileName, app.tempArray, '-append');
            
            % Reset the tempArrayCounter and tempArray
            app.tempArrayCounter = 0;
            app.tempArray = [];
        end


        end
        if app.RunTECSwitch.Value == "Off"
            app.pwmNow = 0; % turn off power to the TEC
            % Send the PWM value to the Arduino
            fprintf(app.port4, 'PWM %d\n', app.pwmNow);
        end
        end

        % Value changed function: SettemperatureEditField
        function SettemperatureEditFieldValueChanged(app, event)
            app.temperatureSet = app.SettemperatureEditField.Value;
        end

        % Value changed function: ProportionalKSlider
        function ProportionalKSliderValueChanged(app, event)
            value = app.ProportionalKSlider.Value;
            
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
          %  app.selectedTab = app.TabGroup2.SelectedTab;
            
        end

        % Button pushed function: ZerointegralButton
        function ZerointegralButtonPushed(app, event)
            app.Integral = 0;
        end

        % Callback function: not associated with a component
        function IntegralEditFieldValueChanged(app, event)
         
            
        end

        % Value changed function: SettemperatureEditField_2
        function SettemperatureEditField_2ValueChanged(app, event)
          app.temperatureSet = app.SettemperatureEditField_2.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {554, 554};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HeatCoolLampLabel = uilabel(app.LeftPanel);
            app.HeatCoolLampLabel.Text = {'Heat'; 'Cool'};
            app.HeatCoolLamp = uilamp(app.LeftPanel);
            app.TemperatureCEditFieldLabel = uilabel(app.LeftPanel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C)';
            app.TemperatureCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TemperatureCEditField.ValueDisplayFormat = '%.1f';
            app.TemperatureCEditField.Editable = 'off';
            app.PWMEditFieldLabel = uilabel(app.LeftPanel);
            app.PWMEditFieldLabel.Text = 'PWM';
            app.PWMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PWMEditField.Limits = [0 255];
            app.PWMEditField.ValueDisplayFormat = '%g';
            app.PWMEditField.ValueChangedFcn = createCallbackFcn(app, @PWMEditFieldValueChanged, true);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.PWMSliderLabel = uilabel(app.ManualTab);
            app.PWMSliderLabel.Text = 'PWM';
            app.PWMSlider = uislider(app.ManualTab);
            app.PWMSlider.Limits = [0 255];
            app.PWMSlider.MajorTicks = [0 64 128 192 255];
            app.PWMSlider.ValueChangedFcn = createCallbackFcn(app, @PWMSliderValueChanged, true);
            app.PWMSlider.Enable = 'off';
            app.HeatCoolSwitchLabel = uilabel(app.ManualTab);
            app.HeatCoolSwitchLabel.Text = 'Heat/Cool';
            app.HeatCoolSwitch = uiswitch(app.ManualTab, 'slider');
            app.HeatCoolSwitch.Items = {'Heat', 'Cool'};
            app.HeatCoolSwitch.ItemsData = [1 0];
            app.HeatCoolSwitch.ValueChangedFcn = createCallbackFcn(app, @HeatCoolSwitchValueChanged, true);
            app.HeatCoolSwitch.Enable = 'off';
            app.HeatCoolSwitch.Value = 1;
            app.ProportionalTab = uitab(app.TabGroup);
            app.ProportionalTab.Title = 'Proportional';
            app.ProportionalKSliderLabel = uilabel(app.ProportionalTab);
            app.ProportionalKSliderLabel.Text = 'Proportional (K)';
            app.ProportionalKSlider = uislider(app.ProportionalTab);
            app.ProportionalKSlider.ValueChangedFcn = createCallbackFcn(app, @ProportionalKSliderValueChanged, true);
            app.ProportionalKSlider.Enable = 'off';
            app.SettemperatureEditFieldLabel = uilabel(app.ProportionalTab);
            app.SettemperatureEditFieldLabel.Text = 'Set temperature';
            app.SettemperatureEditField = uieditfield(app.ProportionalTab, 'numeric');
            app.SettemperatureEditField.Limits = [-10 60];
            app.SettemperatureEditField.ValueDisplayFormat = '%.1f';
            app.SettemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @SettemperatureEditFieldValueChanged, true);
            app.SettemperatureEditField.Value = 20;
            app.PITab = uitab(app.TabGroup);
            app.PITab.Title = 'P/I';
            app.ProportionalKSliderLabel_2 = uilabel(app.PITab);
            app.ProportionalKSliderLabel_2.Text = 'Proportional gain';
            app.ProportionalKSlider_2 = uislider(app.PITab);
            app.IntegralgainSliderLabel = uilabel(app.PITab);
            app.IntegralgainSliderLabel.Text = 'Integral gain';
            app.IntegralgainSlider = uislider(app.PITab);
            app.IntegralgainSlider.Limits = [0 1];
            app.ZerointegralButton = uibutton(app.PITab, 'push');
            app.ZerointegralButton.ButtonPushedFcn = createCallbackFcn(app, @ZerointegralButtonPushed, true);
            app.ZerointegralButton.Text = 'Zero integral';
            app.IntegralEditFieldLabel = uilabel(app.PITab);
            app.IntegralEditFieldLabel.Text = 'Integral';
            app.IntegralEditField = uieditfield(app.PITab, 'numeric');
            app.SettemperatureEditField_2Label = uilabel(app.PITab);
            app.SettemperatureEditField_2Label.Text = 'Set temperature';
            app.SettemperatureEditField_2 = uieditfield(app.PITab, 'numeric');
            app.SettemperatureEditField_2.Limits = [-10 60];
            app.SettemperatureEditField_2.ValueDisplayFormat = '%.1f';
            app.SettemperatureEditField_2.ValueChangedFcn = createCallbackFcn(app, @SettemperatureEditField_2ValueChanged, true);
            app.SettemperatureEditField_2.Value = 20;
            app.RunTECSwitchLabel = uilabel(app.LeftPanel);
            app.RunTECSwitchLabel.Text = 'Run TEC';
            app.RunTECSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.RunTECSwitch.ValueChangedFcn = createCallbackFcn(app, @RunTECSwitchValueChanged, true);
            app.ControlButtonGroup = uibuttongroup(app.LeftPanel);
            app.ControlButtonGroup.Title = 'Control';
            app.ManualButton = uitogglebutton(app.ControlButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.ProportionalButton = uitogglebutton(app.ControlButtonGroup);
            app.ProportionalButton.Text = 'Proportional';
            app.PIButton = uitogglebutton(app.ControlButtonGroup);
            app.PIButton.Text = 'P/I';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.ManualTab_2 = uitab(app.TabGroup2);
            app.ManualTab_2.Title = 'Manual';
            app.TPlot = uiaxes(app.ManualTab_2);
            title(app.TPlot, 'Temperature')
            xlabel(app.TPlot, 'Time (s)')
            ylabel(app.TPlot, 'Temperature')
            zlabel(app.TPlot, 'Z')
            app.PWMPlot = uiaxes(app.ManualTab_2);
            title(app.PWMPlot, 'PWM')
            xlabel(app.PWMPlot, 'Time (s)')
            ylabel(app.PWMPlot, 'PWM')
            zlabel(app.PWMPlot, 'Z')
            app.ProportionalTab_2 = uitab(app.TabGroup2);
            app.ProportionalTab_2.Title = 'Proportional';
            app.GridLayout2 = uigridlayout(app.ProportionalTab_2);
            app.PropErrorPlot = uiaxes(app.GridLayout2);
            title(app.PropErrorPlot, 'Error: T - T_{set} (C)')
            xlabel(app.PropErrorPlot, 'Time (s)')
            ylabel(app.PropErrorPlot, 'Error (C)')
            zlabel(app.PropErrorPlot, 'Z')
            app.TPlot_2 = uiaxes(app.GridLayout2);
            title(app.TPlot_2, 'Temperature (C)')
            xlabel(app.TPlot_2, 'Time (s)')
            ylabel(app.TPlot_2, 'Temperature (C)')
            zlabel(app.TPlot_2, 'Z')
            app.PropPWMPlot = uiaxes(app.GridLayout2);
            title(app.PropPWMPlot, 'PWM')
            xlabel(app.PropPWMPlot, 'Time (s)')
            ylabel(app.PropPWMPlot, 'PWM')
            zlabel(app.PropPWMPlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEC_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end