classdef testappV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        ManualTab                   matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        PWMSliderLabel              matlab.ui.control.Label
        PWMSlider                   matlab.ui.control.Slider
        HeatCoolSwitch              matlab.ui.control.Switch
        PropTab                     matlab.ui.container.Tab
        SettempEditField            matlab.ui.control.NumericEditField
        SettempEditFieldLabel       matlab.ui.control.Label
        ProportionalEditField       matlab.ui.control.NumericEditField
        ProportionalEditFieldLabel  matlab.ui.control.Label
        ProportionalSlider          matlab.ui.control.Slider
        ProportionalSliderLabel     matlab.ui.control.Label
        PITab                       matlab.ui.container.Tab
        HeatCoolLamp                matlab.ui.control.Lamp
        HeatCoolLabel               matlab.ui.control.Label
        OnOffSwitch                 matlab.ui.control.ToggleSwitch
        OnOffSwitchLabel            matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        PWMPlot                     matlab.ui.control.UIAxes
        TPlot                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
   % Sept. 30, 2023
   
   % Controls voltage and direction of current to TEC by hand.
    
   % Works with Arduino Uno code: ThermistorTimeSerialOutForMatlab_20230930.ino
   % Plots strip chart of temperature and pwm values.
   % Writes to Arduino via Serial line: HeatCool = 0 means cool and HeatCool = 1 means heat.
   % Writes to Arduino via Serial line: pwmNow ranges from [0,255]. 
   
   
    properties (Access = private)
        port4 % port 4 on the arduino
        fileID % this is the output file handle for the arduino
        TempData % Description
        fileName
        temperatureNow = 20; % Description
        timeNow = 0; % Description
        pwmNow = 0; % Ranges from [-255,255]. Negative values mean cooling, positive mean heating
        Array_Start = 2;
        Time;
        PWMData = 0;
        HeatCool = 1;  % HeatCool = 0 means cool and HeatCool = 1 means heat.
        ValuesToTrack=200;
        tempArrayLength = 10;  % Number of serial reads before writing to file
        tempArrayCounter = 0;  % Counter for serial reads
        tempArray;
        lineTempPlot;
        linePWMPlot;
        a; % arduino connection
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
       %Find and close any open serial ports
       openSerialPorts = instrfindall('Type', 'serial');

        if ~isempty(openSerialPorts)
            % Close each open serial port
            for i = 1:length(openSerialPorts)
                fclose(openSerialPorts(i));
                delete(openSerialPorts(i));
            end
            disp('Closed all open serial ports.');
        else
            disp('No open serial ports found.');
        end
        
        % Set the serial port and baud rate
        serialPort = '/dev/cu.usbmodem141201';  % Replace with serial port listed in Arduino IDE
        baudRate = 9600;  % Make sure it matches the Arduino code
        
      % app.a = arduino(serialPort);
        
        
        % Open the serial port
        app.port4 = serial(serialPort, 'BaudRate', baudRate);
        fopen(app.port4);
        
        % Create a file to save the data in the current director
        
        % Generate a timestamp for the filename
        timestamp = datestr(now, 'yyyymmdd_HHMMSS');
        
        % Create the file name with timestamp
        app.fileName = ['temperature_data_', timestamp, '.txt'];
        
        % Create the file for writing in the current directory
        app.fileID = fopen(app.fileName, 'w');
        
        % Write the file header
        fprintf(app.fileID, 'Temperature\tTime\n');
        
        % To specify a different directory for writing the file, 
        %    provide the full file path when opening the file.
        
        % fileName = '/path/to/directory/temperature_data.txt';
        % fileID = fopen(fileName, 'w');
        
        % Replace "/path/to/directory" with the 
        %  actual directory path where you want to save the file. 
        
             % Initialize the variables
    
         % Read the data from the Arduino serial port
        data = fscanf(app.port4);
        
        % Extract numbers (2 x 1 double) using str2double
        tokens = split(data);
        numbers = str2double(tokens(~isnan(str2double(tokens))));
        app.temperatureNow = numbers(1);
        app.timeNow = numbers(2);
        
        %app.port4.pinMode(8,'output') - temperature, PWM and time data
        app.TempData = zeros(1,app.ValuesToTrack);
        app.PWMData = zeros(1,app.ValuesToTrack);
        app.Time = zeros(1,app.ValuesToTrack);
        
        app.TempData(:) = app.temperatureNow;
        app.PWMData(:) = app.pwmNow;
       
        app.lineTempPlot = line('Parent', app.TPlot, 'XData', [], 'YData', []);  % stuff for strip plot
        app.linePWMPlot = line('Parent', app.PWMPlot, 'XData', [], 'YData', []);   % stuff for strip plot
        app.HeatCool = str2num(app.HeatCoolSwitch.Value);
            if app.HeatCool == 1
            app.HeatCoolLamp.Color = [1 0 0]; % red - heat
            elseif app.HeatCool == 0
            app.HeatCoolLamp.Color = [0 1 0]; % green - cool
        end
        end

        % Value changed function: OnOffSwitch
        function OnOffSwitchValueChanged(app, event)
            
        while app.OnOffSwitch.Value == 1
        % Read the data from the Arduino serial port
        data = fscanf(app.port4);
        
        % Extract numbers from arduino serial line (3 x 1 double) using str2double
        tokens = split(data);
        numbers = str2double(tokens(~isnan(str2double(tokens))));
        app.temperatureNow = numbers(1);
        app.timeNow = numbers(2);
        % app.pwmNow = numbers(3);
        
        
        % Shift the time and temperature arrays
        app.Time = [app.Time(2:end), app.timeNow];
        app.TempData = [app.TempData(2:end), app.temperatureNow];
        app.PWMData = [app.PWMData(2:end), app.pwmNow];
        
        % Update the temperature strip chart data
        
        set(app.lineTempPlot, 'XData', app.Time, 'YData', app.TempData);
        
        % Set the x-axis limits
        xlim(app.TPlot, [app.Time(1), app.Time(end)]);
        
        % Set the y-axis limits. Autoscale.
        ylim(app.TPlot, [min(app.TempData) - 0.1, max(app.TempData) + 0.1]);
        
        % Update the PWM strip chart data
        
        set(app.linePWMPlot, 'XData', app.Time, 'YData', app.PWMData);
        
        % Set the x-axis limits
        xlim(app.PWMPlot, [app.Time(1), app.Time(end)]);
        
        % Set the y-axis limits. Autoscale.
        ylim(app.PWMPlot, [min(app.PWMData), max(app.PWMData) + 1]);
        
        % Update the plot
        drawnow;
        
        % Display the extracted numbers
        disp(['Temp: ', num2str(app.temperatureNow), ' (C) ', 'Time: ', num2str(app.timeNow), ' (s)  PWM: ' ,num2str(app.pwmNow), '  Heat/Cool: ', num2str(app.HeatCool)]);
    
        % Store the data in tempArray
        app.tempArrayCounter = app.tempArrayCounter + 1;
        app.tempArray(app.tempArrayCounter, :) = [app.temperatureNow, app.timeNow];
        
        % Check if it's time to write to file
        if app.tempArrayCounter == app.tempArrayLength
            % Write tempArray to the file and append it
            dlmwrite(app.fileName, app.tempArray, '-append');
            
            % Reset the tempArrayCounter and tempArray
            app.tempArrayCounter = 0;
            app.tempArray = [];
        end


        end
        if app.OnOffSwitch.Value == 0
            app.pwmNow = 0; % turn off power to the TEC
            % Send the PWM value to the Arduino
            fprintf(app.port4, 'PWM %d\n', app.pwmNow);
        end
        end

        % Callback function: PWMSlider, PWMSlider
        function PWMSliderValueChanged(app, event)
            app.pwmNow = round(app.PWMSlider.Value);
            % Send the PWM value to the Arduino
            fprintf(app.port4, 'PWM %d\n', app.pwmNow);
        end

        % Value changed function: HeatCoolSwitch
        function HeatCoolSwitchValueChanged(app, event)
            app.HeatCool = str2num(app.HeatCoolSwitch.Value);
            if app.HeatCool == 1
            app.HeatCoolLamp.Color = [1 0 0]; % red - heat
            elseif app.HeatCool == 0
            app.HeatCoolLamp.Color = [0 1 0]; % green - cool
            end
            % Send the Heat/Cool value to the Arduino (Heat=1, Cool=0)
            fprintf(app.port4, 'Heat/Cool %d\n', app.HeatCool);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fprintf(app.port4, 'PWM %d\n', 0); % turn off the power when the app is closed
            delete(app)
             
        end

        % Value changed function: ProportionalSlider
        function Pro(app, event)

            
        end

        % Callback function: not associated with a component
        function ProportionalSliderValueChanged(app, event)
            value = app.ProportionalSlider.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.OnOffSwitchLabel = uilabel(app.GridLayout3);
            app.OnOffSwitchLabel.Text = 'On/Off';
            app.OnOffSwitch = uiswitch(app.GridLayout3, 'toggle');
            app.OnOffSwitch.ItemsData = [0 1];
            app.OnOffSwitch.ValueChangedFcn = createCallbackFcn(app, @OnOffSwitchValueChanged, true);
            app.OnOffSwitch.Value = 0;
            app.HeatCoolLabel = uilabel(app.GridLayout3);
            app.HeatCoolLabel.Text = {'Heat'; 'Cool'};
            app.HeatCoolLamp = uilamp(app.GridLayout3);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.GridLayout4 = uigridlayout(app.ManualTab);
            app.HeatCoolSwitch = uiswitch(app.GridLayout4, 'slider');
            app.HeatCoolSwitch.Items = {'Heat', 'Cool'};
            app.HeatCoolSwitch.ItemsData = {'1', '0'};
            app.HeatCoolSwitch.ValueChangedFcn = createCallbackFcn(app, @HeatCoolSwitchValueChanged, true);
            app.HeatCoolSwitch.Value = '1';
            app.PWMSlider = uislider(app.GridLayout4);
            app.PWMSlider.Limits = [0 255];
            app.PWMSlider.ValueChangedFcn = createCallbackFcn(app, @PWMSliderValueChanged, true);
            app.PWMSlider.ValueChangingFcn = createCallbackFcn(app, @PWMSliderValueChanged, true);
            app.PWMSliderLabel = uilabel(app.GridLayout4);
            app.PWMSliderLabel.Text = 'PWM';
            app.PropTab = uitab(app.TabGroup);
            app.PropTab.Title = 'Prop';
            app.ProportionalSliderLabel = uilabel(app.PropTab);
            app.ProportionalSliderLabel.Text = 'Proportional';
            app.ProportionalSlider = uislider(app.PropTab);
            app.ProportionalSlider.Limits = [0 10];
            app.ProportionalSlider.ValueChangedFcn = createCallbackFcn(app, @Pro, true);
            app.ProportionalEditFieldLabel = uilabel(app.PropTab);
            app.ProportionalEditFieldLabel.Text = 'Proportional';
            app.ProportionalEditField = uieditfield(app.PropTab, 'numeric');
            app.SettempEditFieldLabel = uilabel(app.PropTab);
            app.SettempEditFieldLabel.Text = 'Set temp';
            app.SettempEditField = uieditfield(app.PropTab, 'numeric');
            app.PITab = uitab(app.TabGroup);
            app.PITab.Title = 'P/I';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.TPlot = uiaxes(app.GridLayout2);
            title(app.TPlot, 'Temperature')
            xlabel(app.TPlot, 'Time')
            ylabel(app.TPlot, 'T (C)')
            zlabel(app.TPlot, 'Z')
            app.PWMPlot = uiaxes(app.GridLayout2);
            title(app.PWMPlot, 'PWM')
            xlabel(app.PWMPlot, 'Time')
            ylabel(app.PWMPlot, 'Duty Cycle')
            zlabel(app.PWMPlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testappV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end